classdef demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectReferenceButton         matlab.ui.control.Button
        SelectTargetButton            matlab.ui.control.Button
        DetectionMethodDropDownLabel  matlab.ui.control.Label
        DetectionMethodDropDown       matlab.ui.control.DropDown
        estimateGeometricTransformCheckBox  matlab.ui.control.CheckBox
        WebcamButton                  matlab.ui.control.Button
        CropButton                    matlab.ui.control.Button
        FindKeypointsButton           matlab.ui.control.Button
        ReferenceImage                matlab.ui.control.UIAxes
        TargetImage                   matlab.ui.control.UIAxes
        ResultImage                   matlab.ui.control.UIAxes
        MatchKeypointsButton          matlab.ui.control.Button
        WebcamButton_2                matlab.ui.control.Button
        SaveReferenceButton           matlab.ui.control.Button
        SaveTargetButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        reference_image_data;
        target_image_data;
        
        reference_image_data_grey
        target_image_data_grey
        
        ref_im_object
        tar_im_object
        
        referencePoints
        targetPoints
        
        cam;
        camActive = false;
        
        
    end
    
    methods (Access = private)
      
        function result = handle_webcam(app, target)
            result = [];
            if(isempty(app.cam))
                try
                    app.cam = webcam(1);
                catch ME
                    msgbox("No Webcam found!");
                    return;
                end
                
            end
            
            if(~app.camActive)
                frame = snapshot(app.cam);
                preview(app.cam, image(target,frame));
                app.camActive = true;
                result = frame;
            else
                result = snapshot(app.cam);
                closePreview(app.cam);
                app.camActive = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(0,'DefaultFigureVisible','off');
        end

        % Button pushed function: SelectReferenceButton
        function SelectReferenceButtonPushed(app, event)
            [reference_file,reference_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(reference_file,0)
                return;
            else
                app.reference_image_data = imread(fullfile(reference_path, reference_file));
                app.ref_im_object = imshow(app.reference_image_data,'Parent',app.ReferenceImage);
                app.CropButton.Enable = 1;
                cla(app.ResultImage);
            end
        end

        % Button pushed function: SelectTargetButton
        function SelectTargetButtonPushed(app, event)
            [target_file,target_path] = uigetfile({'*.png;*.jpg'}, 'Select a Image');
            if isequal(target_file,0)
                return;
            else
                app.target_image_data = imread(fullfile(target_path, target_file));
                imshow(app.target_image_data,'Parent',app.TargetImage);
                cla(app.ResultImage);
            end
        end

        % Button pushed function: FindKeypointsButton
        function FindKeypointsButtonPushed(app, event)
            if(isempty(app.reference_image_data)) 
                msgbox("Reference Image is missing");
                return
            end
            if (isempty(app.target_image_data))
                msgbox("Target Image is missing");
                return
            end
            
           app.reference_image_data_grey = rgb2gray(app.reference_image_data);
           app.target_image_data_grey = rgb2gray(app.target_image_data);
            
            cla(app.ResultImage);
            
            switch app.DetectionMethodDropDown.Value
                case "BRISK"
                    app.referencePoints = detectBRISKFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectBRISKFeatures(app.target_image_data_grey);
                case "FAST"
                    app.referencePoints = detectFASTFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectFASTFeatures(app.target_image_data_grey);
                case "Harris"
                    app.referencePoints = detectHarrisFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectHarrisFeatures(app.target_image_data_grey);
                case "MinEigen"
                    app.referencePoints = detectMinEigenFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectMinEigenFeatures(app.target_image_data_grey);
                case "MSER"
                    app.referencePoints = detectMSERFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectMSERFeatures(app.target_image_data_grey);
                case "ORB"
                    app.referencePoints = detectORBFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectORBFeatures(app.target_image_data_grey);
                case "SURF"
                    app.referencePoints = detectSURFFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectSURFFeatures(app.target_image_data_grey);
                case "KAZE"
                    app.referencePoints = detectKAZEFeatures(app.reference_image_data_grey);
                    app.targetPoints = detectKAZEFeatures(app.target_image_data_grey);
            end
            
            hold(app.ReferenceImage, "on");
            plot(app.referencePoints.selectStrongest(50), app.ReferenceImage);
            hold(app.ReferenceImage, "off");
            
            hold(app.TargetImage, "on");
            plot(app.targetPoints.selectStrongest(50), app.TargetImage);
            hold(app.TargetImage, "off");


        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            imshow(app.reference_image_data, 'InitialMagnification', 200);
            roi = drawrectangle;
            app.reference_image_data = imcrop(app.reference_image_data,roi.Position);
            close all;
            app.ref_im_object = imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            cla(app.ResultImage);
        end

        % Button pushed function: MatchKeypointsButton
        function MatchKeypointsButtonPushed(app, event)
            [referenceFeatures, app.referencePoints] = extractFeatures(app.reference_image_data_grey, app.referencePoints);
            [targetFeatures, app.targetPoints] = extractFeatures(app.target_image_data_grey, app.targetPoints);
            
            
            pairs = matchFeatures(referenceFeatures, targetFeatures);
            matchedReferencePoints = app.referencePoints(pairs(:, 1), :);
            matchedTargetPoints = app.targetPoints(pairs(:, 2), :);
            
            
            if((matchedTargetPoints.length > 2) && (matchedReferencePoints.length > 2))
                
                
                if(app.estimateGeometricTransformCheckBox.Value == 0)
                showMatchedFeatures(app.reference_image_data, app.target_image_data, matchedReferencePoints, ...
                matchedTargetPoints, 'montage', "Parent", app.ResultImage);
                return;
                end
                
                [tform, estimatedReferencePoints, estimatedTargetPoints] = ...
                    estimateGeometricTransform(matchedReferencePoints, matchedTargetPoints, 'affine');
                
                foundPolygon = [1, 1;...                                                                % top-left
                            size(app.reference_image_data, 2), 1;...                                    % top-right
                            size(app.reference_image_data, 2), size(app.reference_image_data, 1);...    % bottom-right
                            1, size(app.reference_image_data, 1);...                                    % bottom-left
                            1, 1];                                                                      % top-left again to close the polygon
            
                newFoundPolygon = transformPointsForward(tform, foundPolygon);
                imshow(app.target_image_data, 'Parent', app.ResultImage);
                hold(app.ResultImage, "on");
                line(app.ResultImage, newFoundPolygon(:, 1), newFoundPolygon(:, 2), 'Color', 'y');
                hold(app.ResultImage, "off");
                
                % TODO display this
                % showMatchedFeatures(app.reference_image_data, app.target_image_data, estimatedReferencePoints, ...
                %    estimatedTargetPoints, 'montage');
                % hold(app.ResultImage, "off");
            else
                msgbox("Not enough features found for matching.");
            end
            
            
        end

        % Button pushed function: WebcamButton
        function WebcamButtonPushed(app, event)
            tmp = handle_webcam(app, app.TargetImage);
            if(isempty(tmp))
                return;
            else
               app.target_image_data = tmp;
               app.tar_im_object = imshow(app.target_image_data,'Parent',app.TargetImage);
            end
            
        end

        % Button pushed function: WebcamButton_2
        function WebcamButton_2Pushed(app, event)
            tmp = handle_webcam(app, app.ReferenceImage);
            if(isempty(tmp))
                return;
            else
               app.CropButton.Enable = 1;
               app.reference_image_data = tmp; 
               app.ref_im_object = imshow(app.reference_image_data,'Parent',app.ReferenceImage);
            end
        end

        % Button pushed function: SaveReferenceButton
        function SaveReferenceButtonPushed(app, event)
            if(~isempty(app.ref_im_object))
                imsave(app.ref_im_object);
            end
        end

        % Button pushed function: SaveTargetButton
        function SaveTargetButtonPushed(app, event)
            if(~isempty(app.tar_im_object))
               imsave(app.tar_im_object);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectReferenceButton = uibutton(app.UIFigure, 'push');
            app.SelectReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SelectReferenceButtonPushed, true);
            app.SelectReferenceButton.Text = {'Select Reference'; ''};
            app.SelectTargetButton = uibutton(app.UIFigure, 'push');
            app.SelectTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectTargetButtonPushed, true);
            app.SelectTargetButton.Text = {'Select Target'; ''};
            app.DetectionMethodDropDownLabel = uilabel(app.UIFigure);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.UIFigure);
            app.DetectionMethodDropDown.Items = {'BRISK', 'FAST', 'Harris', 'MinEigen', 'MSER', 'ORB', 'SURF', 'KAZE'};
            app.DetectionMethodDropDown.Value = 'BRISK';
            app.estimateGeometricTransformCheckBox = uicheckbox(app.UIFigure);
            app.estimateGeometricTransformCheckBox.Text = 'estimateGeometricTransform';
            app.WebcamButton = uibutton(app.UIFigure, 'push');
            app.WebcamButton.ButtonPushedFcn = createCallbackFcn(app, @WebcamButtonPushed, true);
            app.WebcamButton.Text = {'Webcam'; ''};
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Enable = 'off';
            app.CropButton.Text = 'Crop';
            app.FindKeypointsButton = uibutton(app.UIFigure, 'push');
            app.FindKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @FindKeypointsButtonPushed, true);
            app.FindKeypointsButton.Text = 'Find Keypoints';
            app.ReferenceImage = uiaxes(app.UIFigure);
            title(app.ReferenceImage, {''; ''; ''})
            xlabel(app.ReferenceImage, {''; ''})
            ylabel(app.ReferenceImage, {''; ''})
            app.ReferenceImage.XTick = [];
            app.ReferenceImage.YTick = [];
            app.TargetImage = uiaxes(app.UIFigure);
            title(app.TargetImage, {''; ''; ''})
            xlabel(app.TargetImage, {''; ''})
            ylabel(app.TargetImage, {''; ''})
            app.TargetImage.XTick = [];
            app.TargetImage.YTick = [];
            app.ResultImage = uiaxes(app.UIFigure);
            title(app.ResultImage, {''; ''; ''})
            xlabel(app.ResultImage, {''; ''})
            ylabel(app.ResultImage, {''; ''})
            app.ResultImage.XTick = [];
            app.ResultImage.YTick = [];
            app.MatchKeypointsButton = uibutton(app.UIFigure, 'push');
            app.MatchKeypointsButton.ButtonPushedFcn = createCallbackFcn(app, @MatchKeypointsButtonPushed, true);
            app.MatchKeypointsButton.Text = 'Match Keypoints';
            app.WebcamButton_2 = uibutton(app.UIFigure, 'push');
            app.WebcamButton_2.ButtonPushedFcn = createCallbackFcn(app, @WebcamButton_2Pushed, true);
            app.WebcamButton_2.Text = {'Webcam'; ''};
            app.SaveReferenceButton = uibutton(app.UIFigure, 'push');
            app.SaveReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReferenceButtonPushed, true);
            app.SaveReferenceButton.Text = 'Save Reference';
            app.SaveTargetButton = uibutton(app.UIFigure, 'push');
            app.SaveTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTargetButtonPushed, true);
            app.SaveTargetButton.Text = 'Save Target';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end